classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        DataTab                    matlab.ui.container.Tab
        WegdeSubmitButton          matlab.ui.control.Button
        StrategySubmitButton       matlab.ui.control.Button
        ConstrSubmitButton         matlab.ui.control.Button
        StrategyEditFieldLabel     matlab.ui.control.Label
        StrategyEditField          matlab.ui.control.EditField
        WedgesEditFieldLabel       matlab.ui.control.Label
        WedgesEditField            matlab.ui.control.EditField
        ConstraintsEditFieldLabel  matlab.ui.control.Label
        ConstraintsEditField       matlab.ui.control.EditField
        UseDefaultButton           matlab.ui.control.Button
        NothingEnteredLampLabel    matlab.ui.control.Label
        StrategyLamp               matlab.ui.control.Lamp
        NothingEnteredLabel        matlab.ui.control.Label
        WedgesLamp                 matlab.ui.control.Lamp
        NothingEnteredLamp_3Label  matlab.ui.control.Label
        ConstraintsLamp            matlab.ui.control.Lamp
        GameTab                    matlab.ui.container.Tab
    end


    methods (Access = private)

    end


    methods (Access = private)

        % Button pushed function: StrategySubmitButton
        function StrategySubmitButtonFcn(app, event)

            try 
                strategy_filepath = app.StrategyEditField.Value;
                fid_strategy = fopen(strategy_filepath, 'r');
                if fid_strategy == -1
                    app.StrategyLamp.Color = 'red';
                    app.NothingEnteredLampLabel.Text = 'File not Found';
                else
                    app.StrategyLamp.Color = 'green';
                    app.NothingEnteredLampLabel.Text = 'File Loaded';
                    
                end
            catch E
                errordlg(E.identifier,'Could not Process File')
            end

        end

        % Button pushed function: ConstrSubmitButton
        function ConstrSubmitButtonFcn(app, event)
            try 
                constraints_filepath = app.ConstraintsEditField.Value;
                fid_constr = fopen(constraints_filepath, 'r');
                if fid_constr == -1
                    app.ConstraintsLamp.Color = 'red';
                    app.NothingEnteredLamp_3Label.Text = 'File not Found';
                else
                    app.ConstraintsLamp.Color = 'green';
                    app.NothingEnteredLamp_3Label.Text = 'File Loaded';
                    
                end
            catch E
                errordlg(E.identifier,'Could not Process File')
            end
        end

        % Button pushed function: WegdeSubmitButton
        function WegdeSubmitButtonFcn(app, event)
            try 
                wedges_filepath = app.WedgesEditField.Value;
                wedges_fid = fopen( wedges_filepath, 'r');
                if wedges_fid == -1
                    app.WedgesLamp.Color = 'red';
                    app.NothingEnteredLabel.Text = 'File not Found';
                else
                    app.WedgesLamp.Color = 'green';
                    app.NothingEnteredLabel.Text = 'File Loaded';
                    
                end
            catch E
                errordlg(E.identifier,'Could not Process File')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.WegdeSubmitButton = uibutton(app.DataTab, 'push');
            app.WegdeSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @WegdeSubmitButtonFcn, true);
            app.WegdeSubmitButton.Text = 'Wegde submit';
            app.StrategySubmitButton = uibutton(app.DataTab, 'push');
            app.StrategySubmitButton.ButtonPushedFcn = createCallbackFcn(app, @StrategySubmitButtonFcn, true);
            app.StrategySubmitButton.Text = 'Strategy Submit';
            app.ConstrSubmitButton = uibutton(app.DataTab, 'push');
            app.ConstrSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @ConstrSubmitButtonFcn, true);
            app.ConstrSubmitButton.Text = 'Constr Submit';
            app.StrategyEditFieldLabel = uilabel(app.DataTab);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.DataTab, 'text');
            app.WedgesEditFieldLabel = uilabel(app.DataTab);
            app.WedgesEditFieldLabel.Text = 'Wedges';
            app.WedgesEditField = uieditfield(app.DataTab, 'text');
            app.ConstraintsEditFieldLabel = uilabel(app.DataTab);
            app.ConstraintsEditFieldLabel.Text = 'Constraints';
            app.ConstraintsEditField = uieditfield(app.DataTab, 'text');
            app.UseDefaultButton = uibutton(app.DataTab, 'push');
            app.UseDefaultButton.Text = 'Use Default';
            app.NothingEnteredLampLabel = uilabel(app.DataTab);
            app.NothingEnteredLampLabel.Text = 'Nothing Entered';
            app.StrategyLamp = uilamp(app.DataTab);
            app.NothingEnteredLabel = uilabel(app.DataTab);
            app.NothingEnteredLabel.Text = 'Nothing Entered';
            app.WedgesLamp = uilamp(app.DataTab);
            app.NothingEnteredLamp_3Label = uilabel(app.DataTab);
            app.NothingEnteredLamp_3Label.Text = 'Nothing Entered';
            app.ConstraintsLamp = uilamp(app.DataTab);
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end